classdef gui_MoviewReviews < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SentimentScoreTextArea          matlab.ui.control.TextArea
        SentimentScoreTextAreaLabel     matlab.ui.control.Label
        NotFoundWordsTextArea           matlab.ui.control.TextArea
        NotFoundWordsTextAreaLabel      matlab.ui.control.Label
        MostNegativeWordsTextArea       matlab.ui.control.TextArea
        MostNegativeWordsTextAreaLabel  matlab.ui.control.Label
        MostNegativeSentimentTextArea   matlab.ui.control.TextArea
        MostNegativeSentimentTextAreaLabel  matlab.ui.control.Label
        MostPositiveWordsTextArea       matlab.ui.control.TextArea
        MostPositiveWordsTextAreaLabel  matlab.ui.control.Label
        MostPositiveSentimentTextArea   matlab.ui.control.TextArea
        MostPositiveSentimentTextAreaLabel  matlab.ui.control.Label
        NumberNotFoundWordsTextArea     matlab.ui.control.TextArea
        NumberNotFoundWordsTextAreaLabel  matlab.ui.control.Label
        NumberofWordsTextArea           matlab.ui.control.TextArea
        NumberofWordsTextAreaLabel      matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        ReviewTextArea                  matlab.ui.control.TextArea
        EnteryourmoviereviewhereTextAreaLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if strcmp(app.ReviewTextArea.Value, '')
                app.ReviewTextArea.Value = '';
            else
                load('allWords.mat', 'allWords');
                newReview = app.ReviewTextArea.Value;
                [ numRevWords, avgSentiment, neg, pos, notFound ] = analyzeReview(allWords, newReview{1});
                app.NumberofWordsTextArea.Value = num2str(numRevWords);
                app.NumberNotFoundWordsTextArea.Value = num2str(length(notFound));
                app.MostPositiveSentimentTextArea.Value = num2str(sprintf('%.2f',pos(1).sentiment ));
                app.MostNegativeSentimentTextArea.Value = num2str(sprintf('%.2f',neg(1).sentiment ));

                %changing the color of sentiment box
                app.SentimentScoreTextArea.Value = num2str(sprintf('%.2f',avgSentiment ));
                                
                if avgSentiment > 2 %postive, green background
                    app.SentimentScoreTextArea.BackgroundColor = [0 1 0];
                else
                    app.SentimentScoreTextArea.BackgroundColor = [1 0 0];
                end


                %get the pos words in a char array with commas
                posWords = {pos.word};
                if ~isempty(posWords)
                    ch_posWords = [posWords{1}];
                    for i = 2:length(posWords)
                        ch_posWords = [ch_posWords ', ' posWords{i}];
                    end
                else
                    ch_posWords = '';
                end
                app.MostPositiveWordsTextArea.Value = ch_posWords;

                %get the neg words in a char array with commas
                negWords = {neg.word};
                if ~isempty(negWords)
                    ch_negWords = [negWords{1}];
                    for i = 2:length(negWords)
                        ch_negWords = [ch_negWords ', ' negWords{i}];
                    end
                else
                    ch_negWords = '';
                end
                app.MostPositiveWordsTextArea.Value = ch_posWords;

                app.MostNegativeWordsTextArea.Value = {neg.word};
                %get the not found words in a char array with commas
                if ~isempty(notFound)
                    ch_notFound = [notFound{1}];
                    for i = 2:length(notFound)
                        ch_notFound = [ch_notFound ', ' notFound{i}];
                    end
                else
                    ch_notFound = '';
                end
                app.NotFoundWordsTextArea.Value = ch_notFound;
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.NumberofWordsTextArea.Value = '';
            app.NumberNotFoundWordsTextArea.Value = '';
            app.MostPositiveSentimentTextArea.Value = '';
            app.MostNegativeSentimentTextArea.Value = '';
            app.SentimentScoreTextArea.Value = '';
            app.SentimentScoreTextArea.BackgroundColor = [1 1 1];
            app.MostPositiveWordsTextArea.Value = '';
            app.MostNegativeWordsTextArea.Value = '';
            app.NotFoundWordsTextArea.Value = '';
            app.ReviewTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnteryourmoviereviewhereTextAreaLabel = uilabel(app.UIFigure);
            app.EnteryourmoviereviewhereTextAreaLabel.Text = 'Enter your movie review here';
            app.ReviewTextArea = uitextarea(app.UIFigure);
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.NumberofWordsTextAreaLabel = uilabel(app.UIFigure);
            app.NumberofWordsTextAreaLabel.Text = 'Number of Words';
            app.NumberofWordsTextArea = uitextarea(app.UIFigure);
            app.NumberofWordsTextArea.Editable = 'off';
            app.NumberNotFoundWordsTextAreaLabel = uilabel(app.UIFigure);
            app.NumberNotFoundWordsTextAreaLabel.Text = 'Number Not Found Words';
            app.NumberNotFoundWordsTextArea = uitextarea(app.UIFigure);
            app.NumberNotFoundWordsTextArea.Editable = 'off';
            app.MostPositiveSentimentTextAreaLabel = uilabel(app.UIFigure);
            app.MostPositiveSentimentTextAreaLabel.Text = 'Most Positive Sentiment';
            app.MostPositiveSentimentTextArea = uitextarea(app.UIFigure);
            app.MostPositiveSentimentTextArea.Editable = 'off';
            app.MostPositiveWordsTextAreaLabel = uilabel(app.UIFigure);
            app.MostPositiveWordsTextAreaLabel.Text = 'Most Positive Words';
            app.MostPositiveWordsTextArea = uitextarea(app.UIFigure);
            app.MostPositiveWordsTextArea.Editable = 'off';
            app.MostNegativeSentimentTextAreaLabel = uilabel(app.UIFigure);
            app.MostNegativeSentimentTextAreaLabel.Text = 'Most Negative Sentiment';
            app.MostNegativeSentimentTextArea = uitextarea(app.UIFigure);
            app.MostNegativeSentimentTextArea.Editable = 'off';
            app.MostNegativeWordsTextAreaLabel = uilabel(app.UIFigure);
            app.MostNegativeWordsTextAreaLabel.Text = 'Most Negative Words';
            app.MostNegativeWordsTextArea = uitextarea(app.UIFigure);
            app.MostNegativeWordsTextArea.Editable = 'off';
            app.NotFoundWordsTextAreaLabel = uilabel(app.UIFigure);
            app.NotFoundWordsTextAreaLabel.Text = 'Not Found Words';
            app.NotFoundWordsTextArea = uitextarea(app.UIFigure);
            app.NotFoundWordsTextArea.Editable = 'off';
            app.SentimentScoreTextAreaLabel = uilabel(app.UIFigure);
            app.SentimentScoreTextAreaLabel.Text = 'Sentiment Score';
            app.SentimentScoreTextArea = uitextarea(app.UIFigure);
            app.SentimentScoreTextArea.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'movieReviews.png';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_MoviewReviews

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end